classdef KinectConMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DetenervideoButton   matlab.ui.control.Button
        GrabarTensorButton   matlab.ui.control.Button
        IniciarvideoButton   matlab.ui.control.Button
        CmarasDropDown       matlab.ui.control.DropDown
        CmarasDropDownLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CmarasDropDown
        function CmarasDropDownValueChanged(app, event)
            value = app.CmarasDropDown.Value;
            switch value
                case RGB
                    hwInfo = imaqhwinfo('kinect');
                    hwInfo.DeviceInfo(1)
                    colorVid = videoinput('kinect',1);
                    triggerconfig(colorVid,'manual');
                    colorVid.FramesPerTrigger = 100;
                    frame = snapshot(colorVid);
                    im = image(app.UIAxes, zeros(size(frame),'uint8'));
                    axis(app.UIAxes, 'image')
                    start(colorVid, im);
                case Depth
                    hwInfo = imaqhwinfo('kinect');
                    hwInfo.DeviceInfo(2)
                    depthVid = videoinput('kinect',2);
                    triggerconfig(depthVid,'manual');
                    depthVid.FramesPerTrigger = 100;
                    frame = snapshot(depthVid);
                    im = image(app.UIAxes, zeros(size(frame),'uint8'));
                    axis(app.UIAxes, 'image')
                    start(depthVid,im);
                    otherwise
                        statements
            end
        end

        % Button pushed function: IniciarvideoButton
        function IniciarvideoButtonPushed(app, event)
            
        end

        % Button pushed function: GrabarTensorButton
        function GrabarTensorButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CmarasDropDownLabel = uilabel(app.UIFigure);
            app.CmarasDropDownLabel.Text = 'Cámaras';
            app.CmarasDropDown = uidropdown(app.UIFigure);
            app.CmarasDropDown.Items = {'RGB', 'Depth'};
            app.CmarasDropDown.ValueChangedFcn = createCallbackFcn(app, @CmarasDropDownValueChanged, true);
            app.CmarasDropDown.Value = 'RGB';
            app.IniciarvideoButton = uibutton(app.UIFigure, 'push');
            app.IniciarvideoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarvideoButtonPushed, true);
            app.IniciarvideoButton.Text = 'Iniciar video';
            app.GrabarTensorButton = uibutton(app.UIFigure, 'push');
            app.GrabarTensorButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarTensorButtonPushed, true);
            app.GrabarTensorButton.Text = 'Grabar Tensor';
            app.DetenervideoButton = uibutton(app.UIFigure, 'push');
            app.DetenervideoButton.Text = 'Detener video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KinectConMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end